classdef obfuscateModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ObfuscateModelUIFigure         matlab.ui.Figure
        HidePanel                      matlab.ui.container.Panel
        BlockNamesCheckBox_2           matlab.ui.control.CheckBox
        SubsystemContentPreviewCheckBox  matlab.ui.control.CheckBox
        SubsystemPortLabelsCheckBox    matlab.ui.control.CheckBox
        RecurseIntoModelReferencesCheckBox  matlab.ui.control.CheckBox
        SelectAllButton                matlab.ui.control.Button
        DeselectAllButton              matlab.ui.control.Button
        CurrentlySelectedSystemCheckBox  matlab.ui.control.CheckBox
        SystemEditField                matlab.ui.control.EditField
        SystemEditFieldLabel           matlab.ui.control.Label
        ObfuscateButton                matlab.ui.control.Button
        RenamePanel                    matlab.ui.container.Panel
        BlockNamesCheckBox             matlab.ui.control.CheckBox
        SimulinkFunctionNamesCheckBox  matlab.ui.control.CheckBox
        SimulinkFunctionArgumentsCheckBox  matlab.ui.control.CheckBox
        StateflowPanel                 matlab.ui.container.Panel
        StatesCheckBox                 matlab.ui.control.CheckBox
        BoxesCheckBox                  matlab.ui.control.CheckBox
        ChartsCheckBox                 matlab.ui.control.CheckBox
        StateflowSwitch                matlab.ui.control.Switch
        EventsCheckBox                 matlab.ui.control.CheckBox
        StateflowPortsCheckBox         matlab.ui.control.CheckBox
        GotoFromsTagsCheckBox          matlab.ui.control.CheckBox
        DataStoreMemoryCheckBox        matlab.ui.control.CheckBox
        ConstantsCheckBox              matlab.ui.control.CheckBox
        RemovePanel                    matlab.ui.container.Panel
        ModelReferencesCheckBox        matlab.ui.control.CheckBox
        ModelInformationCheckBox       matlab.ui.control.CheckBox
        BlockCallbacksCheckBox         matlab.ui.control.CheckBox
        ColorsPanel                    matlab.ui.container.Panel
        ColorSwitch                    matlab.ui.control.Switch
        ColourAnnotationsCheckBox      matlab.ui.control.CheckBox
        ColourBlocksCheckBox           matlab.ui.control.CheckBox
        AnnotationsCheckBox            matlab.ui.control.CheckBox
        DescriptionsCheckBox           matlab.ui.control.CheckBox
        DocBlocksCheckBox              matlab.ui.control.CheckBox
        LibraryLinksCheckBox           matlab.ui.control.CheckBox
        SignalNamesCheckBox            matlab.ui.control.CheckBox
        MasksCheckBox                  matlab.ui.control.CheckBox
    end

    
    methods (Access = private)
        function updateCurrentSystem(app)
            value = app.CurrentlySelectedSystemCheckBox.Value;
            opensys = gcs;
            if value && ~strcmp(opensys, 'simulink')
                app.SystemEditField.Value = opensys;
                drawnow;
            end               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateCurrentSystem(app);
        end

        % Value changed function: ColorSwitch
        function ColorSwitchValueChanged(app, event)
            value = app.ColorSwitch.Value;
            if strcmpi(value, 'On')
                app.ColourBlocksCheckBox.Enable = 'On';
                app.ColourAnnotationsCheckBox.Enable = 'On';
            elseif strcmpi(value, 'Off')
                app.ColourBlocksCheckBox.Enable = 'Off';
                app.ColourAnnotationsCheckBox.Enable = 'Off';
            end
        end

        % Value changed function: StateflowSwitch
        function StateflowSwitchValueChanged(app, event)
            value = app.StateflowSwitch.Value;
            if strcmpi(value, 'On')
                app.ChartsCheckBox.Enable = 'On';
                app.StateflowPortsCheckBox.Enable = 'On';
                app.EventsCheckBox.Enable = 'On';
                app.StatesCheckBox.Enable = 'On';
                app.BoxesCheckBox.Enable = 'On';
            elseif strcmpi(value, 'Off')
                app.ChartsCheckBox.Enable = 'Off';
                app.StateflowPortsCheckBox.Enable = 'Off';
                app.EventsCheckBox.Enable = 'Off';
                app.StatesCheckBox.Enable = 'Off';
                app.BoxesCheckBox.Enable = 'Off';
            end
        end

        % Value changed function: CurrentlySelectedSystemCheckBox
        function CurrentlySelectedSystemCheckBoxValueChanged(app, event)
            value = app.CurrentlySelectedSystemCheckBox.Value;
            if value
                updateCurrentSystem(app);
                app.SystemEditField.Enable = 'Off';
                app.SystemEditFieldLabel.Enable = 'Off';
            else
                app.SystemEditField.Enable = 'On';
                app.SystemEditFieldLabel.Enable = 'On';
            end
        end

        % Button pushed function: ObfuscateButton
        function ObfuscateButtonPushed(app, event)
            % Model argument
            if app.CurrentlySelectedSystemCheckBox.Value
                sys = gcs;
                if isempty(sys)
                    uialert(app.ObfuscateModelUIFigure, 'There is no currently opened or loaded model.', 'Model not found')
                    return;
                end
                sys = strtok(sys, '/');
            else
                sys = app.SystemEditField.Value;
                [~, sys, ~] = fileparts(sys);
                if isempty(sys)
                    uialert(app.ObfuscateModelUIFigure, 'No model or subsystem is selected.', 'Model not found')
                    return;
                end
                if ~bdIsLoaded(sys)
                    uialert(app.ObfuscateModelUIFigure, 'Model is not loaded.', 'Model not found')
                    return;
                end
            end
            
            if strcmp(get_param(sys, 'Lock'), 'on')
                input = uiconfirm(app.ObfuscateModelUIFigure, 'Unlock model?', 'Confirm Unlock');
                if strcmp(input, 'OK')
                    set_param(sys, 'Lock', 'off');
                else
                    return
                end
            end
                
            % Build argument list
            if strcmpi(app.ColorSwitch.Value, 'On')
                removecolorblocks = app.ColourBlocksCheckBox.Value;
                removecolorannotations = app.ColourAnnotationsCheckBox.Value;
            else
                removecolorblocks = 0;
                removecolorannotations = 0;
            end
            
            if strcmpi(app.StateflowSwitch.Value, 'On')
                sfcharts = app.ChartsCheckBox.Value;
                sfports  = app.StateflowPortsCheckBox.Value;
                sfevents = app.EventsCheckBox.Value;
                sfstates = app.StatesCheckBox.Value;
                sfboxes  = app.BoxesCheckBox.Value;
            else
                sfcharts = 0;
                sfports  = 0;
                sfevents = 0;
                sfstates = 0;
                sfboxes  = 0;
            end
            args = {...
                'removemasks',            app.MasksCheckBox.Value, ...
                'removelibrarylinks',     app.LibraryLinksCheckBox.Value, ...
                'removemodelreferences',  app.ModelReferencesCheckBox.Value, ...
                'removesignalnames',      app.SignalNamesCheckBox.Value, ...
                'removedocblocks',        app.DocBlocksCheckBox.Value, ...
                'removeannotations',      app.AnnotationsCheckBox.Value, ...
                'removedescriptions',     app.DescriptionsCheckBox.Value, ...
                'removeblockcallbacks',   app.BlockCallbacksCheckBox.Value, ...
                'removemodelinformation', app.ModelInformationCheckBox.Value, ...
                'removecolorblocks',      removecolorblocks, ...
                'removecolorannotations', removecolorannotations, ...
                'renameblocks',           app.BlockNamesCheckBox.Value, ...
                'renameconstants',        app.ConstantsCheckBox.Value, ...
                'renamegotofromtag',      app.GotoFromsTagsCheckBox.Value, ...
                'renamedatastorename',    app.DataStoreMemoryCheckBox.Value, ...
                'renamearguments',        app.SimulinkFunctionArgumentsCheckBox.Value, ...
                'renamefunctions',        app.SimulinkFunctionNamesCheckBox.Value, ...
                'hideblocknames',         app.BlockNamesCheckBox_2.Value, ...
                'hidecontentpreview',     app.SubsystemContentPreviewCheckBox.Value, ...
                'hideportlabels',         app.SubsystemPortLabelsCheckBox.Value, ...
                'sfcharts',               sfcharts, ...
                'sfports',                sfports, ...
                'sfevents',               sfevents, ...
                'sfstates',               sfstates, ...
                'sfboxes',                sfboxes, ...
                'recursemodels',          app.RecurseIntoModelReferencesCheckBox.Value};
            obfuscateModel(sys, [], args{:}, app.ObfuscatecurrentsubsystemonlyCheckBox.Value);
            clear global fcnNum;
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames, 'CheckBox', ...
                'IgnoreCase', false));
            for i = 1:numel(allCheckBoxNamesList)
                if ~strcmp(allCheckBoxNamesList{i}, 'CurrentlySelectedSystemCheckBox') ...
                        && ~strcmp(allCheckBoxNamesList{i}, 'RecurseIntoModelReferencesCheckBox')
                    %if sliders are in 'Off' position, ignore deselection
                    if strcmp(app.ColorSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Blocks" "Annotations"]) || strcmp(app.StateflowSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Charts" "Ports" "Events" "States" "Boxes"])
                        %do nothing
                    else
                        app.(allCheckBoxNamesList{i}).Value = false;
                    end
                end
            end
            app.RecurseIntoModelReferencesCheckBox.Enable = 'on';
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            fieldNames = fieldnames(app);
            allCheckBoxNamesList = fieldNames(contains(fieldNames, 'CheckBox', ...
                'IgnoreCase', false));
            for i = 1:numel(allCheckBoxNamesList)
                if ~strcmp(allCheckBoxNamesList{i}, 'CurrentlySelectedSystemCheckBox') ...
                        && ~strcmp(allCheckBoxNamesList{i}, 'RecurseIntoModelReferencesCheckBox')
                    %if sliders are in 'Off' position, ignore selection
                    if strcmp(app.ColorSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Blocks" "Annotations"]) || strcmp(app.StateflowSwitch.Value, 'Off') && ismember(app.(allCheckBoxNamesList{i}).Text, ["Charts" "Ports" "Events" "States" "Boxes"])
                        %do nothing
                    else
                        app.(allCheckBoxNamesList{i}).Value = true;
                    end
                end
            end
            app.RecurseIntoModelReferencesCheckBox.Value = false;
            app.RecurseIntoModelReferencesCheckBox.Enable = 'off';
            app.ModelReferencesCheckBox.Enable = 'on';
        end

        % Window button motion function: ObfuscateModelUIFigure
        function ObfuscateModelUIFigureWindowButtonMotion(app, event)
            updateCurrentSystem(app);
        end

        % Value changed function: ModelReferencesCheckBox
        function ModelReferencesCheckBoxValueChanged(app, event)
            checked = app.ModelReferencesCheckBox.Value;
            if checked
                app.RecurseIntoModelReferencesCheckBox.Value = false;
                app.RecurseIntoModelReferencesCheckBox.Enable = 'off';
            else
                app.RecurseIntoModelReferencesCheckBox.Enable = 'on';
            end
        end

        % Value changed function: RecurseIntoModelReferencesCheckBox
        function RecurseIntoModelReferencesCheckBoxValueChanged(app, event)
            checked = app.RecurseIntoModelReferencesCheckBox.Value;
            if checked
                app.ModelReferencesCheckBox.Value = false;
                app.ModelReferencesCheckBox.Enable = 'off';
            else
                app.ModelReferencesCheckBox.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ObfuscateModelUIFigure = uifigure('Visible', 'off');
            app.ObfuscateModelUIFigure.Name = 'Obfuscate Model';
            app.ObfuscateModelUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @ObfuscateModelUIFigureWindowButtonMotion, true);
            app.RemovePanel = uipanel(app.ObfuscateModelUIFigure);
            app.RemovePanel.Tooltip = {'Remove elements from the model.'};
            app.RemovePanel.Title = 'Remove';
            app.MasksCheckBox = uicheckbox(app.RemovePanel);
            app.MasksCheckBox.Tooltip = {'Remove block masks.'};
            app.MasksCheckBox.Text = 'Masks';
            app.MasksCheckBox.Value = true;
            app.SignalNamesCheckBox = uicheckbox(app.RemovePanel);
            app.SignalNamesCheckBox.Tooltip = {'Remove signal names.'};
            app.SignalNamesCheckBox.Text = 'Signal Names';
            app.SignalNamesCheckBox.Value = true;
            app.LibraryLinksCheckBox = uicheckbox(app.RemovePanel);
            app.LibraryLinksCheckBox.Tooltip = {'Remove links to libraries. This includes them in the model directly.'};
            app.LibraryLinksCheckBox.Text = 'Library Links';
            app.LibraryLinksCheckBox.Value = true;
            app.DocBlocksCheckBox = uicheckbox(app.RemovePanel);
            app.DocBlocksCheckBox.Tooltip = {'Remove all DocBlocks.'};
            app.DocBlocksCheckBox.Text = 'DocBlocks';
            app.DocBlocksCheckBox.Value = true;
            app.DescriptionsCheckBox = uicheckbox(app.RemovePanel);
            app.DescriptionsCheckBox.Tooltip = {'Remove descriptive block parameters.'};
            app.DescriptionsCheckBox.Text = 'Descriptions';
            app.DescriptionsCheckBox.Value = true;
            app.AnnotationsCheckBox = uicheckbox(app.RemovePanel);
            app.AnnotationsCheckBox.Tooltip = {'Remove all annotations.'};
            app.AnnotationsCheckBox.Text = 'Annotations';
            app.AnnotationsCheckBox.Value = true;
            app.ColorsPanel = uipanel(app.RemovePanel);
            app.ColorsPanel.Tooltip = {'Remove colours.'};
            app.ColorsPanel.Title = 'Colors';
            app.ColourBlocksCheckBox = uicheckbox(app.ColorsPanel);
            app.ColourBlocksCheckBox.Tooltip = {'Restore default colors for all blocks.'};
            app.ColourBlocksCheckBox.Text = 'Blocks';
            app.ColourBlocksCheckBox.Value = true;
            app.ColourAnnotationsCheckBox = uicheckbox(app.ColorsPanel);
            app.ColourAnnotationsCheckBox.Tooltip = {'Restore default colors for all annotations.'};
            app.ColourAnnotationsCheckBox.Text = 'Annotations';
            app.ColourAnnotationsCheckBox.Value = true;
            app.ColorSwitch = uiswitch(app.ColorsPanel, 'slider');
            app.ColorSwitch.ValueChangedFcn = createCallbackFcn(app, @ColorSwitchValueChanged, true);
            app.ColorSwitch.Tooltip = {'Enable or disable the removal of colors.'};
            app.ColorSwitch.Value = 'On';
            app.BlockCallbacksCheckBox = uicheckbox(app.RemovePanel);
            app.BlockCallbacksCheckBox.Tooltip = {'Clear block callback parameters'; ' such as ''LoadFcn'', ''InitFcn'','; ' etc.'};
            app.BlockCallbacksCheckBox.Text = 'Block Callbacks';
            app.BlockCallbacksCheckBox.Value = true;
            app.ModelInformationCheckBox = uicheckbox(app.RemovePanel);
            app.ModelInformationCheckBox.Tooltip = {'Clear the information stored in the ''Model Properties''.'};
            app.ModelInformationCheckBox.Text = 'Model Information';
            app.ModelInformationCheckBox.Value = true;
            app.ModelReferencesCheckBox = uicheckbox(app.RemovePanel);
            app.ModelReferencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ModelReferencesCheckBoxValueChanged, true);
            app.ModelReferencesCheckBox.Tooltip = {'Remove references to other models.'};
            app.ModelReferencesCheckBox.Text = 'Model References';
            app.RenamePanel = uipanel(app.ObfuscateModelUIFigure);
            app.RenamePanel.Tooltip = {'Rename model elements to be generic.'; ''};
            app.RenamePanel.Title = 'Rename';
            app.ConstantsCheckBox = uicheckbox(app.RenamePanel);
            app.ConstantsCheckBox.Tooltip = {'Rename the ''Value'' parameter of Constant blocks.'};
            app.ConstantsCheckBox.Text = 'Constants';
            app.ConstantsCheckBox.Value = true;
            app.DataStoreMemoryCheckBox = uicheckbox(app.RenamePanel);
            app.DataStoreMemoryCheckBox.Tooltip = {'Rename ther ''DataStoreName'' parameter of Data Store Memory, Read, and Write blocks.'};
            app.DataStoreMemoryCheckBox.Text = 'Data Store Memory';
            app.DataStoreMemoryCheckBox.Value = true;
            app.GotoFromsTagsCheckBox = uicheckbox(app.RenamePanel);
            app.GotoFromsTagsCheckBox.Tooltip = {'Rename the ''GotoTag'' parameter of Goto and From blocks (the parameter shown inside the  block).'};
            app.GotoFromsTagsCheckBox.Text = 'Goto/Froms Tags';
            app.GotoFromsTagsCheckBox.Value = true;
            app.StateflowPanel = uipanel(app.RenamePanel);
            app.StateflowPanel.Tooltip = {'Rename Stateflow elements to be generic.'};
            app.StateflowPanel.Title = 'Stateflow';
            app.StateflowPortsCheckBox = uicheckbox(app.StateflowPanel);
            app.StateflowPortsCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow inports and outports.'};
            app.StateflowPortsCheckBox.Text = 'Ports';
            app.StateflowPortsCheckBox.Value = true;
            app.EventsCheckBox = uicheckbox(app.StateflowPanel);
            app.EventsCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow events.'};
            app.EventsCheckBox.Text = 'Events';
            app.EventsCheckBox.Value = true;
            app.StateflowSwitch = uiswitch(app.StateflowPanel, 'slider');
            app.StateflowSwitch.ValueChangedFcn = createCallbackFcn(app, @StateflowSwitchValueChanged, true);
            app.StateflowSwitch.Tooltip = {'Enable or disable the renaming of Stateflow elements.'};
            app.StateflowSwitch.Value = 'On';
            app.ChartsCheckBox = uicheckbox(app.StateflowPanel);
            app.ChartsCheckBox.Tooltip = {'Rename the Stateflow chart ''Name'' parameter.'};
            app.ChartsCheckBox.Text = 'Charts';
            app.ChartsCheckBox.Value = true;
            app.BoxesCheckBox = uicheckbox(app.StateflowPanel);
            app.BoxesCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow boxes.'};
            app.BoxesCheckBox.Text = 'Boxes';
            app.BoxesCheckBox.Value = true;
            app.StatesCheckBox = uicheckbox(app.StateflowPanel);
            app.StatesCheckBox.Tooltip = {'Rename the ''Name'' parameter of Stateflow states.'};
            app.StatesCheckBox.Text = 'States';
            app.StatesCheckBox.Value = true;
            app.SimulinkFunctionArgumentsCheckBox = uicheckbox(app.RenamePanel);
            app.SimulinkFunctionArgumentsCheckBox.Tooltip = {'Rename the ''ArgumentName'' parameter of Simulink Function argument in/out ports. Update Simulink Function callers also.'};
            app.SimulinkFunctionArgumentsCheckBox.Text = 'Simulink Function Arguments';
            app.SimulinkFunctionArgumentsCheckBox.Value = true;
            app.SimulinkFunctionNamesCheckBox = uicheckbox(app.RenamePanel);
            app.SimulinkFunctionNamesCheckBox.Tooltip = {'Rename the ''Function Name'' parameter of all triggers. Update Simulink Function callers also.'};
            app.SimulinkFunctionNamesCheckBox.Text = 'Simulink Function Names';
            app.SimulinkFunctionNamesCheckBox.Value = true;
            app.BlockNamesCheckBox = uicheckbox(app.RenamePanel);
            app.BlockNamesCheckBox.Tooltip = {'Change the ''Name'' parameters of all blocks to be generic.'};
            app.BlockNamesCheckBox.Text = 'Block Names';
            app.BlockNamesCheckBox.Value = true;
            app.ObfuscateButton = uibutton(app.ObfuscateModelUIFigure, 'push');
            app.ObfuscateButton.ButtonPushedFcn = createCallbackFcn(app, @ObfuscateButtonPushed, true);
            app.ObfuscateButton.Tooltip = {'Start model obfuscation.'};
            app.ObfuscateButton.Text = 'Obfuscate';
            app.SystemEditFieldLabel = uilabel(app.ObfuscateModelUIFigure);
            app.SystemEditFieldLabel.Enable = 'off';
            app.SystemEditFieldLabel.Text = 'System';
            app.SystemEditField = uieditfield(app.ObfuscateModelUIFigure, 'text');
            app.SystemEditField.Enable = 'off';
            app.SystemEditField.Tooltip = {'Manually specify the model or system name on which to apply the obfuscation.'};
            app.CurrentlySelectedSystemCheckBox = uicheckbox(app.ObfuscateModelUIFigure);
            app.CurrentlySelectedSystemCheckBox.ValueChangedFcn = createCallbackFcn(app, @CurrentlySelectedSystemCheckBoxValueChanged, true);
            app.CurrentlySelectedSystemCheckBox.Tooltip = {'Apply the obfuscation to the currently selected system that is identified with the gcs command.'};
            app.CurrentlySelectedSystemCheckBox.Text = 'Currently Selected System';
            app.CurrentlySelectedSystemCheckBox.Value = true;
            app.DeselectAllButton = uibutton(app.ObfuscateModelUIFigure, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Tooltip = {'Deselect all of the options.'};
            app.DeselectAllButton.Text = 'Deselect All';
            app.SelectAllButton = uibutton(app.ObfuscateModelUIFigure, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Tooltip = {'Select all of the options.'};
            app.SelectAllButton.Text = 'Select All';
            app.RecurseIntoModelReferencesCheckBox = uicheckbox(app.ObfuscateModelUIFigure);
            app.RecurseIntoModelReferencesCheckBox.ValueChangedFcn = createCallbackFcn(app, @RecurseIntoModelReferencesCheckBoxValueChanged, true);
            app.RecurseIntoModelReferencesCheckBox.Tooltip = {'Apply the obfuscation to all nested model references.'};
            app.RecurseIntoModelReferencesCheckBox.Text = 'Recurse into Model References';
            app.RecurseIntoModelReferencesCheckBox.Value = true;
            app.HidePanel = uipanel(app.ObfuscateModelUIFigure);
            app.HidePanel.Tooltip = {'Hide display elements in the model.'};
            app.HidePanel.Title = 'Hide';
            app.SubsystemPortLabelsCheckBox = uicheckbox(app.HidePanel);
            app.SubsystemPortLabelsCheckBox.Tooltip = {'Hide the port labels shown in subsystems.'};
            app.SubsystemPortLabelsCheckBox.Text = 'Subsystem Port Labels';
            app.SubsystemPortLabelsCheckBox.Value = true;
            app.SubsystemContentPreviewCheckBox = uicheckbox(app.HidePanel);
            app.SubsystemContentPreviewCheckBox.Tooltip = {'Hide the Content Preview shown in subsystems.'};
            app.SubsystemContentPreviewCheckBox.Text = 'Subsystem Content Preview';
            app.SubsystemContentPreviewCheckBox.Value = true;
            app.BlockNamesCheckBox_2 = uicheckbox(app.HidePanel);
            app.BlockNamesCheckBox_2.Tooltip = {'Hide all block names.'};
            app.BlockNamesCheckBox_2.Text = 'Block Names';
            app.BlockNamesCheckBox_2.Value = true;
            % Show the figure after all components are created
            app.ObfuscateModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = obfuscateModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ObfuscateModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ObfuscateModelUIFigure)
        end
    end
end